classdef Dialogoptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        I                 matlab.ui.control.NumericEditField
        EditField_2Label  matlab.ui.control.Label
        Regim             matlab.ui.container.ButtonGroup
        ButtRegim3        matlab.ui.control.RadioButton
        ButtRegim2        matlab.ui.control.RadioButton
        ButtRegim1        matlab.ui.control.RadioButton
        Cancel            matlab.ui.control.Button
        D                 matlab.ui.control.NumericEditField
        EditField2Label   matlab.ui.control.Label
        P                 matlab.ui.control.NumericEditField
        EditFieldLabel    matlab.ui.control.Label
        Save              matlab.ui.control.Button
    end


    properties (Access = private) 
        CallingApp   % Main app object
        regim
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, regim, p, d, i, flag)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Update UI with input values
            app.P.Value = p;
            app.D.Value = d;
            app.I.Value = i;
            app.regim=regim;
            if app.regim==2
                app.Regim.SelectedObject=app.ButtRegim1; 
            end
            if app.regim==3
                app.Regim.SelectedObject=app.ButtRegim2; 
            end
            if app.regim==4
                app.Regim.SelectedObject=app.ButtRegim3; 
            end
            
        end

        % Button pushed function: Save
        function SavePushed(app, event)
            % Call main app's public function
            updataopt(app.CallingApp, app.regim, app.P.Value, app.D.Value, app.I.Value,1);
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.OptionsButton.Enable = 'on';
            app.CallingApp.SwitchConnect.Enable= 'on';
            app.CallingApp.SwitchGo.Enable= 'on';
            app.CallingApp.ValueGroup.Enable= 'on';
            app.CallingApp.PlotGo.Enable= 'on';
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Button pushed function: Cancel
        function CancelButtonPushed(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.OptionsButton.Enable = 'on';
            app.CallingApp.SwitchConnect.Enable= 'on';
            app.CallingApp.SwitchGo.Enable= 'on';
            app.CallingApp.ValueGroup.Enable= 'on';
            app.CallingApp.PlotGo.Enable= 'on';
            
            % Delete the dialog box 
            delete(app)
        end

        % Selection changed function: Regim
        function RegimSelectionChanged(app, event)
            selectedButton = app.Regim.SelectedObject;
            if selectedButton==app.ButtRegim1
                 app.regim=2; 
            end
            if selectedButton==app.ButtRegim2
                 app.regim=3;             
            end
            if selectedButton==app.ButtRegim3           
                 app.regim=4;                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.Save = uibutton(app.UIFigure, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SavePushed, true);
            app.Save.Text = 'СОХРАНИТЬ';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.P = uieditfield(app.UIFigure, 'numeric');
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.D = uieditfield(app.UIFigure, 'numeric');
            app.Cancel = uibutton(app.UIFigure, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.Cancel.Text = 'ОТМЕНА';
            app.Regim = uibuttongroup(app.UIFigure);
            app.Regim.SelectionChangedFcn = createCallbackFcn(app, @RegimSelectionChanged, true);
            app.Regim.Title = 'Выбор режима';
            app.ButtRegim1 = uiradiobutton(app.Regim);
            app.ButtRegim1.Text = 'Режим 1';
            app.ButtRegim1.Value = true;
            app.ButtRegim2 = uiradiobutton(app.Regim);
            app.ButtRegim2.Text = 'Режим 2';
            app.ButtRegim3 = uiradiobutton(app.Regim);
            app.ButtRegim3.Text = 'Режим 3';
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = 'Edit Field';
            app.I = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dialogoptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end