classdef Awesome_TAU_stand < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TabGroup          matlab.ui.container.TabGroup
        Tab               matlab.ui.container.Tab
        ValuePanel        matlab.ui.container.Panel
        Label_8           matlab.ui.control.Label
        Stop              matlab.ui.control.Button
        Label_7           matlab.ui.control.Label
        Start             matlab.ui.control.Button
        LampConnect       matlab.ui.control.Lamp
        Label_6           matlab.ui.control.Label
        SwitchConnect     matlab.ui.control.ToggleSwitch
        Tab2              matlab.ui.container.Tab
        kd                matlab.ui.control.NumericEditField
        kdEditFieldLabel  matlab.ui.control.Label
        ki                matlab.ui.control.NumericEditField
        kiEditFieldLabel  matlab.ui.control.Label
        kp                matlab.ui.control.NumericEditField
        rewLabel          matlab.ui.control.Label
        Label_13          matlab.ui.control.Label
        Label_12          matlab.ui.control.Label
        Label_11          matlab.ui.control.Label
        Label_10          matlab.ui.control.Label
        OptionsMotor      matlab.ui.control.Button
        SpeedMotor        matlab.ui.control.NumericEditField
        Label_9           matlab.ui.control.Label
        StopMotor         matlab.ui.control.Button
        StartMotor        matlab.ui.control.Button
        Tab3              matlab.ui.container.Tab
        PlotPanel         matlab.ui.container.Panel
        PlotGo            matlab.ui.control.Button
        ValueGroup        matlab.ui.container.ButtonGroup
        D                 matlab.ui.control.RadioButton
        I                 matlab.ui.control.RadioButton
        P                 matlab.ui.control.RadioButton
        PID               matlab.ui.control.RadioButton
        Absspeed          matlab.ui.control.RadioButton
        Oborot            matlab.ui.control.RadioButton
        Ugol              matlab.ui.control.RadioButton
        Current           matlab.ui.control.RadioButton
        Speed             matlab.ui.control.RadioButton
        Tab4              matlab.ui.container.Tab
        UITable2          matlab.ui.control.Table
    end

    
    properties (Access = private)
       %Modbus
        flag = 0; % Description
        m; % Description
        regim=3;
        flagerror=0;
        %
        value=0;
        f;
        DialogApp; % Dialog box app 
        SystemstartApp;
        flagtab=1;
    end
    
    methods (Access = private)
        
        function Modbus(app)
              while(app.flag)
                  if app.flagtab==2
                     app.SpeedMotor.Value=modbusread(app.m,34);
                     app.kp.Value=double(modbusread(app.m,12));
                     app.ki.Value=double(modbusread(app.m,14));
                     app.kd.Value=double(modbusread(app.m,16));
                  end
                  if app.flagtab==4
                     zapros_analog=read(app.m,'holdingregs',1,50,'uint16');
                     zapros_disk=read(app.m,'coils',1,50);
                     zapros_disk=transpose(zapros_disk);
                     zapros_analog=transpose(zapros_analog);
                     app.UITable2.Data=([zapros_analog,zapros_disk]);
                  end
                  pause(1)
              end
              clear m;

        end        
    end

    methods (Access = public)

         function EnableAll(app)
            app.Start.Enable = 'on';
            app.Stop.Enable = 'on';
            app.SwitchConnect.Enable= 'on';
            app.OptionsMotor.Enable = 'on';
            app.SpeedMotor.Enable = 'on';
            %app.StopMotor.Enable = 'on';
            app.StartMotor.Enable = 'on';
            app.ValueGroup.Enable= 'on';
            app.PlotGo.Enable= 'on';
        end
        
        function DisableAll(app)
            app.Start.Enable = 'off';
            app.Stop.Enable = 'off';
            app.SwitchConnect.Enable= 'off';
            app.OptionsMotor.Enable = 'off';
            app.SpeedMotor.Enable = 'off';
            app.StopMotor.Enable = 'off';
            app.StartMotor.Enable = 'off';
            app.ValueGroup.Enable= 'off';
            app.PlotGo.Enable= 'off';           
        end
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Store main app object
             DisableAll(app);   
             app.SwitchConnect.Enable= 'on';
        end

        % Value changed function: SwitchConnect
        function SwitchConnectValueChanged(app, event)
            value_switch = app.SwitchConnect.Value;
            if strcmp(value_switch,'Вкл')
               try
                    app.m = modbus('tcpip', '192.168.1.123', 80,'Timeout',3);
               catch ME
                   if(strcmp(ME.identifier,'instrument:modbus:transportUnavailable'))                   
                        app.flagerror=1;
                        app.f=errordlg('Ошибка подключения: проверьте подключение микроконтроллера к компьютеру или параметры сети компьютера.','Ошбика подключения');
                        app.SwitchConnect.Value=('Выкл');
                   end
                end
                if app.flagerror==0
                    app.LampConnect.Color='g';
                    app.flag = 1;
                    EnableAll(app);
                    Modbus(app)
                end
            else
                app.flag=0;
                pause(1);
                app.LampConnect.Color='r';
                DisableAll(app);
                app.SwitchConnect.Enable='on';
            end
        end

        % Selection changed function: ValueGroup
        function ValueGroupSelectionChanged(app, event)
            selectedButton = app.ValueGroup.SelectedObject;
            if selectedButton==app.Current
                app.value=0;
                cla;
            end
            if selectedButton==app.Speed
                app.value=2;
                cla;
            end
            if selectedButton==app.Ugol
                app.value=4;
                cla;
            end
            if selectedButton==app.Oborot
                app.value=10;
                cla;
            end   
            if selectedButton==app.Absspeed
                app.value=8;
                cla;
            end
            if selectedButton==app.PID
                app.value=26;
                cla;
            end
            if selectedButton==app.P
                app.value=28;
                cla;
            end
            if selectedButton==app.I
                app.value=30;
                cla;
            end
            if selectedButton==app.D
                app.value=32;
                cla;
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
         
        if app.flag==0
           delete(app.DialogApp)
           delete(app)  
        else
           app.f=errordlg('Для закрытия приложения необходимо отключится от контроллера','Ошbбка закрытия приложения'); 
        end
        end

        % Button pushed function: PlotGo
        function PlotGoButtonPushed(app, event)
            DisableAll(app);
            
            % Open the options dialog and pass inputs
            app.DialogApp = grafikapp(app, app.value,app.m);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab==app.Tab
                app.flagtab=1;
            end
            if selectedTab==app.Tab2
                app.flagtab=2;
            end
            if selectedTab==app.Tab3
                app.flagtab=3;
            end
            if selectedTab==app.Tab4
                app.flagtab=4;
            end
            
        end

        % Button pushed function: Stop
        function StopButtonPushed(app, event)
            write(app.m,'coils',1,(1)); 
            write(app.m,'coils',2,(0)); 
            app.Start.Enable = 'on';
        end

        % Button pushed function: Start
        function StartButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            DisableAll(app);
            % Open the options dialog and pass inputs
            app.SystemstartApp = Startsystem(app,app.m);
        end

        % Button pushed function: StartMotor
        function StartMotorButtonPushed(app, event)
             app.StopMotor.Enable='on';
             app.StartMotor.Enable='off';
             write(app.m,'coils',2+1,(1)); 
             flag_regim=read(app.m,'coils',10+1,1);
             if flag_regim==1
                app.kp.Enable='off';
                app.ki.Enable='off';
                app.kd.Enable='off';
             end
        end

        % Button pushed function: StopMotor
        function StopMotorButtonPushed(app, event)
             app.StopMotor.Enable='off';
             app.StartMotor.Enable='on';
             write(app.m,'coils',2+1,(0));
             flag_regim=read(app.m,'coils',10+1,1);
             if flag_regim==1
                app.kp.Enable='on';
                app.ki.Enable='on';
                app.kd.Enable='on';
             end

        end

        % Value changed function: SpeedMotor
        function SpeedMotorValueChanged(app, event)
            valuespeed = app.SpeedMotor.Value;
            modbuswrite(app.m,34,valuespeed);
        end

        % Button pushed function: OptionsMotor
        function OptionsMotorButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            DisableAll(app);
            % Open the options dialog and pass inputs
            app.SystemstartApp = OptMotor(app,app.m);
        end

        % Value changed function: kd
        function kdValueChanged(app, event)
            valuekd = app.kd.Value;
            modbuswrite(app.m,16,valuekd);
        end

        % Value changed function: ki
        function kiValueChanged(app, event)
            valueki = app.ki.Value;
            modbuswrite(app.m,14,valueki);
        end

        % Value changed function: kp
        function kpValueChanged(app, event)
            valuekp = app.kp.Value;
            modbuswrite(app.m,12,valuekp);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Состояние системы';
            app.ValuePanel = uipanel(app.Tab);
            app.ValuePanel.Title = 'Состояние системы';
            app.SwitchConnect = uiswitch(app.ValuePanel, 'toggle');
            app.SwitchConnect.Items = {'Выкл', 'Вкл'};
            app.SwitchConnect.ValueChangedFcn = createCallbackFcn(app, @SwitchConnectValueChanged, true);
            app.SwitchConnect.Value = 'Выкл';
            app.Label_6 = uilabel(app.ValuePanel);
            app.Label_6.Text = 'Подключение';
            app.LampConnect = uilamp(app.ValuePanel);
            app.Start = uibutton(app.ValuePanel, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.Start.Icon = 'Start.png';
            app.Start.Text = '';
            app.Label_7 = uilabel(app.ValuePanel);
            app.Label_7.Text = 'Запуск системы';
            app.Stop = uibutton(app.ValuePanel, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.Stop.Icon = 'Stop.png';
            app.Stop.Text = '';
            app.Label_8 = uilabel(app.ValuePanel);
            app.Label_8.Text = 'Остановка системы';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Управление двигателем';
            app.StartMotor = uibutton(app.Tab2, 'push');
            app.StartMotor.ButtonPushedFcn = createCallbackFcn(app, @StartMotorButtonPushed, true);
            app.StartMotor.Icon = 'Start.png';
            app.StartMotor.Text = {''; ''};
            app.StopMotor = uibutton(app.Tab2, 'push');
            app.StopMotor.ButtonPushedFcn = createCallbackFcn(app, @StopMotorButtonPushed, true);
            app.StopMotor.Icon = 'Stop.png';
            app.StopMotor.Enable = 'off';
            app.StopMotor.Text = {''; ''; ''};
            app.Label_9 = uilabel(app.Tab2);
            app.Label_9.Text = '';
            app.SpeedMotor = uieditfield(app.Tab2, 'numeric');
            app.SpeedMotor.ValueChangedFcn = createCallbackFcn(app, @SpeedMotorValueChanged, true);
            app.OptionsMotor = uibutton(app.Tab2, 'push');
            app.OptionsMotor.ButtonPushedFcn = createCallbackFcn(app, @OptionsMotorButtonPushed, true);
            app.OptionsMotor.Icon = 'opt.png';
            app.OptionsMotor.Text = '';
            app.Label_10 = uilabel(app.Tab2);
            app.Label_10.Text = 'Запустить двигатель';
            app.Label_11 = uilabel(app.Tab2);
            app.Label_11.Text = 'Остановить двигатель';
            app.Label_12 = uilabel(app.Tab2);
            app.Label_12.Text = {'Заданная скорость двигателя'; ''};
            app.Label_13 = uilabel(app.Tab2);
            app.Label_13.Text = {'Настройки управления '; ''};
            app.rewLabel = uilabel(app.Tab2);
            app.rewLabel.Text = 'kp';
            app.kp = uieditfield(app.Tab2, 'numeric');
            app.kp.ValueChangedFcn = createCallbackFcn(app, @kpValueChanged, true);
            app.kp.Enable = 'off';
            app.kiEditFieldLabel = uilabel(app.Tab2);
            app.kiEditFieldLabel.Text = 'ki';
            app.ki = uieditfield(app.Tab2, 'numeric');
            app.ki.ValueChangedFcn = createCallbackFcn(app, @kiValueChanged, true);
            app.ki.Enable = 'off';
            app.kdEditFieldLabel = uilabel(app.Tab2);
            app.kdEditFieldLabel.Text = 'kd';
            app.kd = uieditfield(app.Tab2, 'numeric');
            app.kd.ValueChangedFcn = createCallbackFcn(app, @kdValueChanged, true);
            app.kd.Enable = 'off';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Графики';
            app.PlotPanel = uipanel(app.Tab3);
            app.PlotPanel.Title = 'График';
            app.ValueGroup = uibuttongroup(app.PlotPanel);
            app.ValueGroup.SelectionChangedFcn = createCallbackFcn(app, @ValueGroupSelectionChanged, true);
            app.ValueGroup.Title = 'Выбор величины';
            app.Speed = uiradiobutton(app.ValueGroup);
            app.Speed.Text = 'Инкрементальный энкодер скорость';
            app.Speed.Value = true;
            app.Current = uiradiobutton(app.ValueGroup);
            app.Current.Text = 'Ток';
            app.Ugol = uiradiobutton(app.ValueGroup);
            app.Ugol.Text = 'Абсолютный энкодер угол';
            app.Oborot = uiradiobutton(app.ValueGroup);
            app.Oborot.Text = {'Абсолютный энкодер обороты'; ''};
            app.Absspeed = uiradiobutton(app.ValueGroup);
            app.Absspeed.Text = {'Абсолютный энкодер скорость'; ''};
            app.PID = uiradiobutton(app.ValueGroup);
            app.PID.Text = 'Воздействие ПИД регулятора';
            app.P = uiradiobutton(app.ValueGroup);
            app.P.Text = {'Воздействие от пропорциональной части'; ''};
            app.I = uiradiobutton(app.ValueGroup);
            app.I.Text = 'Воздействие от интеграл. части';
            app.D = uiradiobutton(app.ValueGroup);
            app.D.Text = {'Воздействие от дифф. части'; ''};
            app.PlotGo = uibutton(app.PlotPanel, 'push');
            app.PlotGo.ButtonPushedFcn = createCallbackFcn(app, @PlotGoButtonPushed, true);
            app.PlotGo.Enable = 'off';
            app.PlotGo.Text = 'НАЧАТЬ';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Режим отладки';
            app.UITable2 = uitable(app.Tab4);
            app.UITable2.ColumnName = {'Аналоговый регистры'; 'Дискретные регистры'};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Awesome_TAU_stand

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end