classdef Dialogoptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        UITable_2  matlab.ui.control.Table
        UITable    matlab.ui.control.Table
        Cancel     matlab.ui.control.Button
        Save       matlab.ui.control.Button
    end


    properties (Access = private) 
        CallingApp
        regim
        matlab;
        Reg_disk;
        Reg_analg;
        
    end
    
    methods (Access = private)
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, disk, reg)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.UITable.Data=disk;
            app.UITable_2.Data=reg;
            % Update UI with input values
            
            
        end

        % Button pushed function: Save
        function SavePushed(app, event)
            % Call main app's public function
            app.Reg_disk=app.UITable.Data;
            app.Reg_analg=app.UITable_2.Data;
            updataopt(app.CallingApp,app.Reg_disk,app.Reg_analg);
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.OptionsButton.Enable = 'on';
            app.CallingApp.SwitchConnect.Enable= 'on';
            app.CallingApp.ValueGroup.Enable= 'on';
            app.CallingApp.PlotGo.Enable= 'on';
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Button pushed function: Cancel
        function CancelButtonPushed(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.OptionsButton.Enable = 'on';
            app.CallingApp.SwitchConnect.Enable= 'on';
            app.CallingApp.ValueGroup.Enable= 'on';
            app.CallingApp.PlotGo.Enable= 'on';
            
            % Delete the dialog box 
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.Save = uibutton(app.UIFigure, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SavePushed, true);
            app.Save.Text = 'СОХРАНИТЬ';
            app.Cancel = uibutton(app.UIFigure, 'push');
            app.Cancel.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.Cancel.Text = 'ОТМЕНА';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Управление'};
            app.UITable.RowName = {'Стоп система'; 'Измерение'; 'Мотор'; 'Нагрузка 1'; 'Нагрузка 2'; 'Нагрузка 3'; 'Флаг тока'; 'Флаг абс. энкодера'; 'Флаг инкр. энкодера'; 'Флаг контроллера 1'; 'Флаг контроллера 2'; 'Флаг скорости'; 'Флаг angle'; 'Флаг rotate'; 'Флаг напряжения'; 'Статус мотора'; 'Статус тока'; 'Статус инкр. энкодера'; 'Статус абс. энкодера'};
            app.UITable.ColumnEditable = true;
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'Параметры системы управления'};
            app.UITable_2.RowName = {'DATA_CONTR_KP'; 'DATA_CONTR_KI'; 'DATA_CONTR_KD'; 'DATA_CONTR_KP_2'; 'DATA_CONTR_KI_2'; 'DATA_CONTR_KD_2'; 'CONTR_USE_SEN'; 'DATA_FORCE_PID'; 'DATA_FORCE_KP'; 'DATA_FORCE_KI'; 'DATA_FORCE_KD'};
            app.UITable_2.ColumnEditable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dialogoptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end