classdef grafikapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Start     matlab.ui.control.Button
        End       matlab.ui.control.Button
        Grafik    matlab.ui.control.UIAxes
    end


    properties (Access = private) 
        CallingApp;   % Main app object
        value;
        m;
        flagstart=0;
        %Grafik
        x_axis = [0,0];
        speed_A_axis = [0,0];
        update_period = 1;         % How often to update plot, points
        time_window = 10;           % Time window to be shown on figure, sec
        counter = 0;
        new_values = [];
        start_time = 0;
        flaggrafik=0;
        %
    end
    
    methods (Access = private)
        
        function readvalue(app)
              while(app.flagstart)
                app.counter = app.counter + 1;
                if app.value==0
                    app.new_values = double(modbusread(app.m,app.value));
                end
                if app.value==2
                    app.new_values = double(modbusread(app.m,app.value));
                end
                if app.value==4
                    app.new_values = double(modbusread(app.m,app.value));
                end
                if app.value==10
                    app.new_values = double(modbusread(app.m,app.value));
                end
                if app.value==8
                    app.new_values = double(modbusread(app.m,app.value));
                end
                if app.value==26
                    app.new_values = double(modbusread(app.m,app.value));
                end
                if app.value==28
                    app.new_values = double(modbusread(app.m,app.value));
                end
                if app.value==30
                    app.new_values = double(modbusread(app.m,app.value));
                end
                if app.value==32
                    app.new_values = double(modbusread(app.m,app.value));
                end
                % Add new points to the plot and forget last one if necessary
                if app.x_axis(end) >= app.time_window
                    app.x_axis = [app.x_axis(2:end) etime(clock, app.start_time)];
                    app.speed_A_axis = [app.speed_A_axis(2:end) app.new_values ];
                else
                    app.x_axis = [app.x_axis etime(clock, app.start_time)];
                    app.speed_A_axis = [app.speed_A_axis app.new_values];
                end
                 % Update plot
                if app.counter == app.update_period
                    plot(app.Grafik,  app.x_axis,  app.speed_A_axis);
                if app.x_axis(end) >= app.time_window
                    app.Grafik.XLim=[app.x_axis(1) app.x_axis(end)];
                end
                drawnow;
                app.counter = 0;
                end
               end
            
               end
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, value, m)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.m=m;
            % Update UI with input values
            app.value=value;
            if app.value==0
                title(app.Grafik, 'Зависимость тока от времени ')
                xlabel(app.Grafik, 'Время, с')
                ylabel(app.Grafik, 'Ток, А') 
            end
            if app.value==2
                title(app.Grafik, 'Зависимость скорости от времени ')
                xlabel(app.Grafik, 'Время, с')
                ylabel(app.Grafik, 'Скорость, ос/с')
            end
            if app.value==4
                title(app.Grafik, 'Зависимость угла от времени ')
                xlabel(app.Grafik, 'Время, с')
                ylabel(app.Grafik, 'Угол, градус')
            end
            if app.value==10
                title(app.Grafik, 'Зависимость оборотов от времени ')
                xlabel(app.Grafik, 'Время, с')
                ylabel(app.Grafik, 'Оборот')
            end
            
        end

        % Button pushed function: End
        function EndPushed(app, event)
            % Enable the Plot Opions button in main app
            app.flagstart=0;
            clear app.x_axis
            clear app.speed_A_axis
            clear app.counter
            app.CallingApp.Start.Enable = 'on';
            app.CallingApp.Stop.Enable = 'on';
            app.CallingApp.SwitchConnect.Enable= 'on';
            app.CallingApp.OptionsMotor.Enable = 'on';
            app.CallingApp.SpeedMotor.Enable = 'on';
            app.CallingApp.StopMotor.Enable = 'on';
            app.CallingApp.StartMotor.Enable = 'on';
            app.CallingApp.ValueGroup.Enable= 'on';
            app.CallingApp.PlotGo.Enable= 'on';
            
            % Delete the dialog box 
            delete(app)
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.flagstart=0;
            clear app.x_axis
            clear app.speed_A_axis
            clear app.counter
            app.CallingApp.Start.Enable = 'on';
            app.CallingApp.Stop.Enable = 'on';
            app.CallingApp.SwitchConnect.Enable= 'on';
            app.CallingApp.OptionsMotor.Enable = 'on';
            app.CallingApp.SpeedMotor.Enable = 'on';
            app.CallingApp.StopMotor.Enable = 'on';
            app.CallingApp.StartMotor.Enable = 'on';
            app.CallingApp.ValueGroup.Enable= 'on';
            app.CallingApp.PlotGo.Enable= 'on';
            
            % Delete the dialog box 
            delete(app)
            
        end

        % Button pushed function: Start
        function StartButtonPushed(app, event)
            app.flagstart=1;
            app.start_time = clock;
            readvalue(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.Grafik = uiaxes(app.UIFigure);
            title(app.Grafik, 'Title')
            xlabel(app.Grafik, 'X')
            ylabel(app.Grafik, 'Y')
            zlabel(app.Grafik, 'Z')
            app.End = uibutton(app.UIFigure, 'push');
            app.End.ButtonPushedFcn = createCallbackFcn(app, @EndPushed, true);
            app.End.Text = 'ЗАКОНЧИТЬ';
            app.Start = uibutton(app.UIFigure, 'push');
            app.Start.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.Start.Text = 'НАЧАТЬ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = grafikapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end