classdef testgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        TabGroup        matlab.ui.container.TabGroup
        Tab             matlab.ui.container.Tab
        ValuePanel      matlab.ui.container.Panel
        OptionsButton   matlab.ui.control.Button
        LampConnect     matlab.ui.control.Lamp
        Label_6         matlab.ui.control.Label
        SwitchConnect   matlab.ui.control.ToggleSwitch
        Tab2            matlab.ui.container.Tab
        EditField       matlab.ui.control.NumericEditField
        EditFieldLabel  matlab.ui.control.Label
        UITable3        matlab.ui.control.Table
        Tab3            matlab.ui.container.Tab
        PlotPanel       matlab.ui.container.Panel
        PlotGo          matlab.ui.control.Button
        ValueGroup      matlab.ui.container.ButtonGroup
        Voltage         matlab.ui.control.RadioButton
        Current         matlab.ui.control.RadioButton
        Speed           matlab.ui.control.RadioButton
        Tab4            matlab.ui.container.Tab
        UITable2        matlab.ui.control.Table
    end

    
    properties (Access = private)
       %Modbus
        flag = 0; % Description
        m; % Description
        registranalog;
        regim=3;
        flagerror=0;
        f;
        mutex=0;
        flagwrite=0;
        %
        value=1;
        indec=0;
    
        Register=zeros(10,1);
        DialogApp; % Dialog box app 
        flagtab=1;

        Reg_disk;
        Reg=zeros(11,1);
    end
    
    methods (Access = private)
        
        function Modbus(app)
              while(app.flag)
                  %while(app.mutex)
                  %end
                  %app.mutex=1;
                  try
                  if app.flagtab==1
                     app.P.Value=double(modbusread(app.m,12));
                     app.D.Value=double(modbusread(app.m,16));
                     app.I.Value=double(modbusread(app.m,14));
                  end
                  catch
                  end
                  if app.flagtab==2
                     app.Register(1)=modbusread(app.m,0);
                     app.Register(2)=double(modbusread(app.m,2));
                     app.Register(3)=double(modbusread(app.m,4));
                     app.Register(4)=double(modbusread(app.m,6));
                     app.Register(5)=double(modbusread(app.m,8));
                     app.Register(6)=double(modbusread(app.m,10));
                     app.Register(7)=modbusread(app.m,34);
                     app.Register(8)=modbusread(app.m,35);
                     app.Register(9)=modbusread(app.m,36);
                     app.Register(10)=modbusread(app.m,37);
                     app.UITable3.Data=app.Register;
                  end
                  if app.flagtab==4
                     zapros_analog=read(app.m,'holdingregs',1,50,'uint16');
                     zapros_disk=read(app.m,'coils',1,50);
                     zapros_disk=transpose(zapros_disk);
                     zapros_analog=transpose(zapros_analog);
                     app.UITable2.Data=([zapros_analog,zapros_disk]);
                  end
                  %app.mutex=0;
                  pause(1)
              end
              %while(app.mutex)
              %end
              %app.mutex=1;
              write(app.m,'coils',1,(1))
              %app.mutex=0;
              clear m;

        end


    end
    methods (Access = public)
            function updataopt(app,disk,reg)   
                        EnableAll(app);
                        app.Reg_disk=disk;
                        app.Reg=reg;
                        WriteDataOpt(app)
            end

            function  ReadDataOpt(app)
               app.Reg_disk=read(app.m,'coils',1,19);
                for i=1:1:19
                    if app.Reg_disk(i)==1
                        app.Reg_disk(i)=true;
                    end
                    if app.Reg_disk(i)==0
                        app.Reg_disk(i)=false;
                    end
                end
                app.Reg_disk=transpose(app.Reg_disk);
                app.Reg(1)=double(modbusread(app.m,12));
                app.Reg(2)=double(modbusread(app.m,14));
                app.Reg(3)=double(modbusread(app.m,16));
                app.Reg(4)=double(modbusread(app.m,18));
                app.Reg(5)=double(modbusread(app.m,20));
                app.Reg(6)=double(modbusread(app.m,22));
                app.Reg(7)=modbusread(app.m,24);
                app.Reg(8)=double(modbusread(app.m,26));
                app.Reg(9)=double(modbusread(app.m,28));
                app.Reg(10)=double(modbusread(app.m,30));
                app.Reg(11)=double(modbusread(app.m,32));            
            end
        
            function WriteDataOpt(app)
                for i=1:1:18
                    write(app.m,'coils',i,(app.Reg_disk(i,1)));  
                end
                modbuswrite(app.m,12,double(app.Reg(1,1)));
                modbuswrite(app.m,14,double(app.Reg(2,1)));
                modbuswrite(app.m,16,double(app.Reg(3,1)));
                modbuswrite(app.m,18,double(app.Reg(4,1)));
                modbuswrite(app.m,20,double(app.Reg(5,1)));
                modbuswrite(app.m,22,double(app.Reg(6,1)));
                modbuswrite(app.m,24,double(app.Reg(7,1)));
                modbuswrite(app.m,26,double(app.Reg(8,1)));
                modbuswrite(app.m,28,double(app.Reg(9,1)));
                modbuswrite(app.m,30,double(app.Reg(10,1)));
                modbuswrite(app.m,32,double(app.Reg(11,1)));
            end
            function EnableAll(app)
                app.OptionsButton.Enable = 'on';
                app.SwitchConnect.Enable= 'on';
                app.ValueGroup.Enable= 'on';
                app.PlotGo.Enable= 'on';
            end
        
        function DisableAll(app)
            app.OptionsButton.Enable = 'off';
            app.SwitchConnect.Enable= 'off';
            app.ValueGroup.Enable= 'off';
            app.PlotGo.Enable= 'off';            
        end
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Store main app object
             DisableAll(app);   
             app.SwitchConnect.Enable= 'on';
        end

        % Value changed function: SwitchConnect
        function SwitchConnectValueChanged(app, event)
            value_switch = app.SwitchConnect.Value;
            if strcmp(value_switch,'Вкл')
               try
                    app.m = modbus('tcpip', '192.168.1.123', 80,'Timeout',3);
               catch ME
                   if(strcmp(ME.identifier,'instrument:modbus:transportUnavailable'))                   
                        app.flagerror=1;
                        app.f=errordlg('Ошибка подключения: проверьте подключение микроконтроллера к компьютеру или параметры сети компьютера.','Ошбика подключения');
                        app.SwitchConnect.Value=('Выкл');
                   end
                end
                if app.flagerror==0
                    app.OptionsButton.Enable = 'on';
                    app.ValueGroup.Enable= 'on';
                    app.PlotGo.Enable= 'on';
                    app.LampConnect.Color='g';
                    app.flag = 1;
                    Modbus(app)
                end
            else
                app.LampConnect.Color='r';
                app.OptionsButton.Enable = 'off';
                app.ValueGroup.Enable= 'off';
                app.PlotGo.Enable= 'off'; 
            end
        end

        % Selection changed function: ValueGroup
        function ValueGroupSelectionChanged(app, event)
            selectedButton = app.ValueGroup.SelectedObject;
            if selectedButton==app.Speed
                app.value=1;
                cla;
            end
            if selectedButton==app.Current
                app.value=2;
                cla;
            end
            if selectedButton==app.Voltage
                app.value=3;
                cla;
            end            
        end

        % Button pushed function: OptionsButton
        function OptionsButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            DisableAll(app);
            ReadDataOpt(app);
            % Open the options dialog and pass inputs
            app.DialogApp = Dialogoptions(app, app.Reg_disk,app.Reg);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.flag=0;
            pause(1)
            delete(app.DialogApp)
            delete(app)           
        end

        % Button pushed function: PlotGo
        function PlotGoButtonPushed(app, event)
            DisableAll(app);
            
            % Open the options dialog and pass inputs
            app.DialogApp = grafikapp(app, app.value,app.m);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab==app.Tab
                app.flagtab=1;
            end
            if selectedTab==app.Tab2
                app.flagtab=2;
            end
            if selectedTab==app.Tab3
                app.flagtab=3;
            end
            if selectedTab==app.Tab4
                app.flagtab=4;
            end
            
        end

        % Display data changed function: UITable3
        function UITable3DisplayDataChanged(app, event)
            Data=app.UITable3.Data;
            if app.indec==1 
                modbuswrite(app.m,0,Data(app.indec,1));
                app.EditField.Value=Data(app.indec,1);
            end
            if app.indec==2 
                modbuswrite(app.m,2,Data(app.indec,1));
                app.EditField.Value=Data(app.indec,1);
            end
            if app.indec==3 
                modbuswrite(app.m,4,Data(app.indec,1));
                app.EditField.Value=Data(app.indec,1);
            end
            if app.indec==4 
                modbuswrite(app.m,6,Data(app.indec,1));
                app.EditField.Value=Data(app.indec,1);
            end
            if app.indec==5 
                modbuswrite(app.m,8,Data(app.indec,1));
                app.EditField.Value=Data(app.indec,1);
            end
            if app.indec==6 
                modbuswrite(app.m,10,Data(app.indec,1));
                app.EditField.Value=Data(app.indec,1);
            end
            if app.indec==7 
                modbuswrite(app.m,34,Data(app.indec,1));
                app.EditField.Value=Data(app.indec,1);
            end
            if app.indec==8 
                modbuswrite(app.m,35,Data(app.indec,1));
                app.EditField.Value=Data(app.indec,1);
            end
            if app.indec==9 
                modbuswrite(app.m,36,Data(app.indec,1));
                app.EditField.Value=Data(app.indec,1);
            end
            if app.indec==10 
                modbuswrite(app.m,37,Data(app.indec,1));
                app.EditField.Value=Data(app.indec,1);
            end
        end

        % Cell selection callback: UITable3
        function UITable3CellSelection(app, event)
                  
            indices = event.Indices;
            try
            app.indec=indices(1);  
            catch 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Состояние системы';
            app.ValuePanel = uipanel(app.Tab);
            app.ValuePanel.Title = 'Состояние системы';
            app.SwitchConnect = uiswitch(app.ValuePanel, 'toggle');
            app.SwitchConnect.Items = {'Выкл', 'Вкл'};
            app.SwitchConnect.ValueChangedFcn = createCallbackFcn(app, @SwitchConnectValueChanged, true);
            app.SwitchConnect.Value = 'Выкл';
            app.Label_6 = uilabel(app.ValuePanel);
            app.Label_6.Text = 'Подключение';
            app.LampConnect = uilamp(app.ValuePanel);
            app.OptionsButton = uibutton(app.ValuePanel, 'push');
            app.OptionsButton.ButtonPushedFcn = createCallbackFcn(app, @OptionsButtonPushed, true);
            app.OptionsButton.Enable = 'off';
            app.OptionsButton.Text = 'Настройки';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Показатели системы';
            app.UITable3 = uitable(app.Tab2);
            app.UITable3.ColumnName = {'Параметры системы'};
            app.UITable3.RowName = {'Ток'; 'Инкрементальный энкодер'; 'Абсолютный энкодер 1'; 'Абсолютный энкодер 2'; 'Абсолютный энкодер 3'; 'Абсолютный энкодер 4'; 'Текущая скорость'; 'Заданная скорость'; 'Текущее напряжение'; 'Заданное напряжение'};
            app.UITable3.ColumnEditable = true;
            app.UITable3.CellSelectionCallback = createCallbackFcn(app, @UITable3CellSelection, true);
            app.UITable3.DisplayDataChangedFcn = createCallbackFcn(app, @UITable3DisplayDataChanged, true);
            app.EditFieldLabel = uilabel(app.Tab2);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.Tab2, 'numeric');
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Графики';
            app.PlotPanel = uipanel(app.Tab3);
            app.PlotPanel.Title = 'График';
            app.ValueGroup = uibuttongroup(app.PlotPanel);
            app.ValueGroup.SelectionChangedFcn = createCallbackFcn(app, @ValueGroupSelectionChanged, true);
            app.ValueGroup.Enable = 'off';
            app.ValueGroup.Title = 'Выбор величины';
            app.Speed = uiradiobutton(app.ValueGroup);
            app.Speed.Text = 'Скорость';
            app.Speed.Value = true;
            app.Current = uiradiobutton(app.ValueGroup);
            app.Current.Text = 'Ток';
            app.Voltage = uiradiobutton(app.ValueGroup);
            app.Voltage.Text = 'Напряжение';
            app.PlotGo = uibutton(app.PlotPanel, 'push');
            app.PlotGo.ButtonPushedFcn = createCallbackFcn(app, @PlotGoButtonPushed, true);
            app.PlotGo.Enable = 'off';
            app.PlotGo.Text = 'НАЧАТЬ';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Режим отладки';
            app.UITable2 = uitable(app.Tab4);
            app.UITable2.ColumnName = {'Аналоговый регистры'; 'Дискретные регистры'};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end