classdef grafikapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        End       matlab.ui.control.Button
        Grafik    matlab.ui.control.UIAxes
    end


    properties (Access = private) 
        CallingApp   % Main app object
        value
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, value)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Update UI with input values
            app.value=value;
            if app.value==1
                title(app.Grafik, 'Зависимость скорости от времени ')
                xlabel(app.Grafik, 'Время, с')
                ylabel(app.Grafik, 'Скорость, об/с') 
            end
            if app.value==2
                title(app.Grafik, 'Зависимость тока от времени ')
                xlabel(app.Grafik, 'Время, с')
                ylabel(app.Grafik, 'Ток, А')
            end
            if app.value==3
                title(app.Grafik, 'Зависимость напряжения от времени ')
                xlabel(app.Grafik, 'Время, с')
                ylabel(app.Grafik, 'Напряжение, В')
            end
            
        end

        % Button pushed function: End
        function EndPushed(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.OptionsButton.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.OptionsButton.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.Grafik = uiaxes(app.UIFigure);
            title(app.Grafik, 'Title')
            xlabel(app.Grafik, 'X')
            ylabel(app.Grafik, 'Y')
            zlabel(app.Grafik, 'Z')
            app.End = uibutton(app.UIFigure, 'push');
            app.End.ButtonPushedFcn = createCallbackFcn(app, @EndPushed, true);
            app.End.Text = 'ЗАКОНЧИТЬ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = grafikapp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end