classdef testgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TabGroup       matlab.ui.container.TabGroup
        Tab            matlab.ui.container.Tab
        ValuePanel     matlab.ui.container.Panel
        Label_10       matlab.ui.control.Label
        I              matlab.ui.control.NumericEditField
        Label_9        matlab.ui.control.Label
        D              matlab.ui.control.NumericEditField
        Label_8        matlab.ui.control.Label
        P              matlab.ui.control.NumericEditField
        Label_7        matlab.ui.control.Label
        OptionsButton  matlab.ui.control.Button
        LampConnect    matlab.ui.control.Lamp
        Label_6        matlab.ui.control.Label
        SwitchConnect  matlab.ui.control.ToggleSwitch
        LampRejim3     matlab.ui.control.Lamp
        Label_5        matlab.ui.control.Label
        LampRejim2     matlab.ui.control.Lamp
        Label_4        matlab.ui.control.Label
        LampRejim1     matlab.ui.control.Lamp
        Label_3        matlab.ui.control.Label
        Go             matlab.ui.control.Lamp
        Label_2        matlab.ui.control.Label
        SwitchGo       matlab.ui.control.ToggleSwitch
        Tab2           matlab.ui.container.Tab
        Label_19       matlab.ui.control.Label
        Label_18       matlab.ui.control.Label
        cur_volt       matlab.ui.control.NumericEditField
        Label_17       matlab.ui.control.Label
        req_volt       matlab.ui.control.NumericEditField
        Label_16       matlab.ui.control.Label
        cur_speed      matlab.ui.control.NumericEditField
        Label_15       matlab.ui.control.Label
        req_speed      matlab.ui.control.NumericEditField
        Label_14       matlab.ui.control.Label
        abc_encoder    matlab.ui.control.NumericEditField
        Label_13       matlab.ui.control.Label
        Inc_encoder    matlab.ui.control.NumericEditField
        Label_12       matlab.ui.control.Label
        CurrentValue   matlab.ui.control.NumericEditField
        Label_11       matlab.ui.control.Label
        Tab3           matlab.ui.container.Tab
        PlotPanel      matlab.ui.container.Panel
        PlotGo         matlab.ui.control.Button
        ValueGroup     matlab.ui.container.ButtonGroup
        Voltage        matlab.ui.control.RadioButton
        Current        matlab.ui.control.RadioButton
        Speed          matlab.ui.control.RadioButton
        Tab4           matlab.ui.container.Tab
        UITable2       matlab.ui.control.Table
    end

    
    properties (Access = private)
       %Modbus
        flag = 0; % Description
        m; % Description
        registranalog;
        regim=3;
        flagerror=0;
        f;
        mutex=0;
        flagwrite=0;
        %
        value=1;
    
        DialogApp; % Dialog box app 
        flagtab=1;
    end
    
    methods (Access = private)
        
        function Modbus(app)
              while(app.flag)
                  while(app.mutex)
                  end
                  app.mutex=1;
                  if app.flagtab==1
                     app.P.Value=double(modbusread(app.m,15));
                     app.D.Value=double(modbusread(app.m,17));
                     app.I.Value=double(modbusread(app.m,19));
                  end
                  if app.flagtab==2
                     app.CurrentValue.Value=modbusread(app.m,1);
                     app.Inc_encoder.Value=double(modbusread(app.m,3));
                     app.abc_encoder.Value=double(modbusread(app.m,6));
                     app.cur_speed.Value=modbusread(app.m,37);
                     app.req_speed.Value=modbusread(app.m,36);
                     app.cur_volt.Value=modbusread(app.m,39);
                     app.req_volt.Value=modbusread(app.m,38);
                  end
                  if app.flagtab==4
                     zapros_analog=read(app.m,'holdingregs',1,50,'uint16');
                     zapros_disk=read(app.m,'coils',1,50);
                     zapros_disk=transpose(zapros_disk);
                     zapros_analog=transpose(zapros_analog);
                     app.UITable2.Data=([zapros_analog,zapros_disk]);
                  end
                  app.mutex=0;
                  pause(1)
              end
              while(app.mutex)
              end
              app.mutex=1;
              write(app.m,'coils',1,(1))
              app.mutex=0;
              clear m;

        end

        
    end
    methods (Access = public)
            function updataopt(app, regim, p, d, i, flag)
                app.P.Value=p;
                app.D.Value=d;
                app.I.Value=i;
                app.regim=regim;

                app.flagwrite=flag;
                if(app.flagwrite)
                    if app.regim==3
                        app.LampRejim1.Color='g'; 
                        app.LampRejim2.Color='r'; 
                        app.LampRejim3.Color='r'; 
                        while(app.mutex)
                        end
                        app.mutex=1;
                        write(app.m,'coils',double(3+1),(1));
                        write(app.m,'coils',double(4+1),(0));
                        write(app.m,'coils',double(5+1),(0));
                        app.mutex=0;
                    end
                    if app.regim==4
                        app.LampRejim1.Color='r'; 
                        app.LampRejim2.Color='g'; 
                        app.LampRejim3.Color='r'; 
                        while(app.mutex)
                        end
                        app.mutex=1;
                        write(app.m,'coils',3+1,(0));
                        write(app.m,'coils',4+1,(1));
                        write(app.m,'coils',5+1,(0));
                        app.mutex=0;
                    end
                    if app.regim==5
                        app.LampRejim1.Color='r'; 
                        app.LampRejim2.Color='r'; 
                        app.LampRejim3.Color='g';
                        while(app.mutex)
                        end
                        app.mutex=1;
                        write(app.m,'coils',3+1,(0));
                        write(app.m,'coils',4+1,(0));
                        write(app.m,'coils',5+1,(1));
                        app.mutex=0;
                    end
                    while(app.mutex)
                    end
                    app.mutex=1;
                    modbuswrite(app.m,15,p);
                    modbuswrite(app.m,17,d);
                    modbuswrite(app.m,19,i);
                    app.mutex=0;
                    % Re-enable the Plot Options button
                    EnableAll(app);
                end

            end
        
        function EnableAll(app)
        app.OptionsButton.Enable = 'on';
        app.SwitchConnect.Enable= 'on';
        app.SwitchGo.Enable= 'on';
        app.ValueGroup.Enable= 'on';
        app.PlotGo.Enable= 'on';
        end
        
        function DisableAll(app)
        app.OptionsButton.Enable = 'off';
        app.SwitchConnect.Enable= 'off';
        app.SwitchGo.Enable= 'off';
        app.ValueGroup.Enable= 'off';
        app.PlotGo.Enable= 'off';            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Store main app object
              updataopt(app, app.regim, app.P.Value, app.D.Value, app.I.Value,0);
        end

        % Value changed function: SwitchGo
        function SwitchGoValueChanged(app, event)
         value_switch = app.SwitchGo.Value;
            if strcmp(value_switch,'Вкл')
                app.Go.Color='g';
                while(app.mutex)
                end
                app.mutex=1;
                write(app.m,'coils',0+1,(1));
                write(app.m,'coils',app.regim+1,(1))
                app.mutex=0;
            else
                app.Go.Color='r';
                while(app.mutex)
                end
                app.mutex=1;
                write(app.m,'coils',0+1,(0));
                app.mutex=0;
            end
            
        end

        % Value changed function: SwitchConnect
        function SwitchConnectValueChanged(app, event)
            value_switch = app.SwitchConnect.Value;
            if strcmp(value_switch,'Вкл')
               try
                    app.m = modbus('tcpip', '192.168.1.123', 80,'Timeout',3);
               catch ME
                   if(strcmp(ME.identifier,'instrument:modbus:transportUnavailable'))                   
                        app.flagerror=1;
                        app.f=errordlg('Ошибка подключения: проверьте подключение микроконтроллера к компьютеру или параметры сети компьютера.','Ошбика подключения');
                        app.SwitchConnect.Value=('Выкл');
                   end
                end
                if app.flagerror==0
                    app.OptionsButton.Enable = 'on';
                    app.SwitchGo.Enable= 'on';
                    app.ValueGroup.Enable= 'on';
                    app.PlotGo.Enable= 'on';
                    app.LampConnect.Color='g';
                    app.flag = 1;
                    Modbus(app)
                end
            else
                app.LampConnect.Color='r';
                app.OptionsButton.Enable = 'off';
                app.SwitchGo.Enable= 'off';
                app.ValueGroup.Enable= 'off';
                app.PlotGo.Enable= 'off'; 
            end
        end

        % Selection changed function: ValueGroup
        function ValueGroupSelectionChanged(app, event)
            selectedButton = app.ValueGroup.SelectedObject;
            if selectedButton==app.Speed
                app.value=1;
                cla;
            end
            if selectedButton==app.Current
                app.value=2;
                cla;
            end
            if selectedButton==app.Voltage
                app.value=3;
                cla;
            end            
        end

        % Button pushed function: OptionsButton
        function OptionsButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            DisableAll(app);
            
            % Open the options dialog and pass inputs
            while(app.mutex)
            end
            app.DialogApp = Dialogoptions(app, app.regim, app.P.Value, app.D.Value, app.I.Value,0);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DialogApp)
            delete(app)           
        end

        % Button pushed function: PlotGo
        function PlotGoButtonPushed(app, event)
            DisableAll(app);
            
            % Open the options dialog and pass inputs
            while(app.mutex)
            end
            app.DialogApp = grafikapp(app, app.value,app.m);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            while(app.mutex)
            end
            if selectedTab==app.Tab
                app.flagtab=1;
            end
            if selectedTab==app.Tab2
                app.flagtab=2;
            end
            if selectedTab==app.Tab3
                app.flagtab=3;
            end
            if selectedTab==app.Tab4
                app.flagtab=4;
            end
            
        end

        % Value changed function: req_speed
        function req_speedValueChanged(app, event)
            value_speed = app.req_speed.Value;
            while(app.mutex)
            end
            app.mutex=1;
            modbuswrite(app.m,36,value_speed);
            app.mutex=0;
        end

        % Value changed function: req_volt
        function req_voltValueChanged(app, event)
            value_volt = app.req_volt.Value;
            while(app.mutex)
            end
            app.mutex=1;
            modbuswrite(app.m,38,value_volt);
            app.mutex=0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Состояние системы';
            app.ValuePanel = uipanel(app.Tab);
            app.ValuePanel.Title = 'Состояние системы';
            app.SwitchGo = uiswitch(app.ValuePanel, 'toggle');
            app.SwitchGo.Items = {'Выкл', 'Вкл'};
            app.SwitchGo.ValueChangedFcn = createCallbackFcn(app, @SwitchGoValueChanged, true);
            app.SwitchGo.Enable = 'off';
            app.SwitchGo.Value = 'Выкл';
            app.Label_2 = uilabel(app.ValuePanel);
            app.Label_2.Text = {'Запуск'; ''};
            app.Go = uilamp(app.ValuePanel);
            app.Label_3 = uilabel(app.ValuePanel);
            app.Label_3.Text = 'Режим 1';
            app.LampRejim1 = uilamp(app.ValuePanel);
            app.Label_4 = uilabel(app.ValuePanel);
            app.Label_4.Text = 'Режим 2';
            app.LampRejim2 = uilamp(app.ValuePanel);
            app.Label_5 = uilabel(app.ValuePanel);
            app.Label_5.Text = 'Режим 3';
            app.LampRejim3 = uilamp(app.ValuePanel);
            app.SwitchConnect = uiswitch(app.ValuePanel, 'toggle');
            app.SwitchConnect.Items = {'Выкл', 'Вкл'};
            app.SwitchConnect.ValueChangedFcn = createCallbackFcn(app, @SwitchConnectValueChanged, true);
            app.SwitchConnect.Value = 'Выкл';
            app.Label_6 = uilabel(app.ValuePanel);
            app.Label_6.Text = 'Подключение';
            app.LampConnect = uilamp(app.ValuePanel);
            app.OptionsButton = uibutton(app.ValuePanel, 'push');
            app.OptionsButton.ButtonPushedFcn = createCallbackFcn(app, @OptionsButtonPushed, true);
            app.OptionsButton.Enable = 'off';
            app.OptionsButton.Text = 'Настройки';
            app.Label_7 = uilabel(app.ValuePanel);
            app.Label_7.Text = 'П';
            app.P = uieditfield(app.ValuePanel, 'numeric');
            app.P.Editable = 'off';
            app.Label_8 = uilabel(app.ValuePanel);
            app.Label_8.Text = 'Д';
            app.D = uieditfield(app.ValuePanel, 'numeric');
            app.D.Editable = 'off';
            app.Label_9 = uilabel(app.ValuePanel);
            app.Label_9.Text = 'И';
            app.I = uieditfield(app.ValuePanel, 'numeric');
            app.I.Editable = 'off';
            app.Label_10 = uilabel(app.ValuePanel);
            app.Label_10.Text = 'Текущие параметры системы';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Показатели системы';
            app.Label_11 = uilabel(app.Tab2);
            app.Label_11.Text = 'Ток';
            app.CurrentValue = uieditfield(app.Tab2, 'numeric');
            app.Label_12 = uilabel(app.Tab2);
            app.Label_12.Text = 'Инкрементальный энкодер';
            app.Inc_encoder = uieditfield(app.Tab2, 'numeric');
            app.Label_13 = uilabel(app.Tab2);
            app.Label_13.Text = {'Абсолютный энкодер'; ''};
            app.abc_encoder = uieditfield(app.Tab2, 'numeric');
            app.Label_14 = uilabel(app.Tab2);
            app.Label_14.Text = 'Заданная скорость';
            app.req_speed = uieditfield(app.Tab2, 'numeric');
            app.req_speed.ValueChangedFcn = createCallbackFcn(app, @req_speedValueChanged, true);
            app.Label_15 = uilabel(app.Tab2);
            app.Label_15.Text = 'Текущая скорость';
            app.cur_speed = uieditfield(app.Tab2, 'numeric');
            app.Label_16 = uilabel(app.Tab2);
            app.Label_16.Text = 'Заданное напряжение';
            app.req_volt = uieditfield(app.Tab2, 'numeric');
            app.req_volt.ValueChangedFcn = createCallbackFcn(app, @req_voltValueChanged, true);
            app.Label_17 = uilabel(app.Tab2);
            app.Label_17.Text = 'Текущее напряжение';
            app.cur_volt = uieditfield(app.Tab2, 'numeric');
            app.Label_18 = uilabel(app.Tab2);
            app.Label_18.Text = 'Показатели системы';
            app.Label_19 = uilabel(app.Tab2);
            app.Label_19.Text = 'Изменяемые показатели системы';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Графики';
            app.PlotPanel = uipanel(app.Tab3);
            app.PlotPanel.Title = 'График';
            app.ValueGroup = uibuttongroup(app.PlotPanel);
            app.ValueGroup.SelectionChangedFcn = createCallbackFcn(app, @ValueGroupSelectionChanged, true);
            app.ValueGroup.Enable = 'off';
            app.ValueGroup.Title = 'Выбор величины';
            app.Speed = uiradiobutton(app.ValueGroup);
            app.Speed.Text = 'Скорость';
            app.Speed.Value = true;
            app.Current = uiradiobutton(app.ValueGroup);
            app.Current.Text = 'Ток';
            app.Voltage = uiradiobutton(app.ValueGroup);
            app.Voltage.Text = 'Напряжение';
            app.PlotGo = uibutton(app.PlotPanel, 'push');
            app.PlotGo.ButtonPushedFcn = createCallbackFcn(app, @PlotGoButtonPushed, true);
            app.PlotGo.Enable = 'off';
            app.PlotGo.Text = 'НАЧАТЬ';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Режим отладки';
            app.UITable2 = uitable(app.Tab4);
            app.UITable2.ColumnName = {'Аналоговый регистры'; 'Дискретные регистры'};
            app.UITable2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end